classdef DeltaKinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        bEditField                    matlab.ui.control.NumericEditField
        BrazorLabel_2                 matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        BrazorLabel                   matlab.ui.control.Label
        hEditField                    matlab.ui.control.NumericEditField
        RadiobasemvilhEditFieldLabel  matlab.ui.control.Label
        rEditField                    matlab.ui.control.NumericEditField
        RadiobasefijarEditFieldLabel  matlab.ui.control.Label
        Titulo                        matlab.ui.control.Label
        Autores                       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        CinemticaDirectaTab           matlab.ui.container.Tab
        pzFKDegEditField              matlab.ui.control.NumericEditField
        pzLabel                       matlab.ui.control.Label
        pyFKDegEditField              matlab.ui.control.NumericEditField
        pyLabel                       matlab.ui.control.Label
        pxFKDegEditField              matlab.ui.control.NumericEditField
        pxLabel                       matlab.ui.control.Label
        q3FKDegEditField              matlab.ui.control.NumericEditField
        q3degLabel                    matlab.ui.control.Label
        q2FKDegEditField              matlab.ui.control.NumericEditField
        q2degLabel                    matlab.ui.control.Label
        q1FKDegEditField              matlab.ui.control.NumericEditField
        q1degEditFieldLabel           matlab.ui.control.Label
        CalcularFKButton              matlab.ui.control.Button
        FKfig                         matlab.ui.control.UIAxes
        CinemticaInversaTab           matlab.ui.container.Tab
        q3IKDegEditField              matlab.ui.control.NumericEditField
        pxLabel_5                     matlab.ui.control.Label
        q2IKDegEditField              matlab.ui.control.NumericEditField
        pxLabel_4                     matlab.ui.control.Label
        q1IKDegEditField              matlab.ui.control.NumericEditField
        pxLabel_3                     matlab.ui.control.Label
        pzIKDegEditField              matlab.ui.control.NumericEditField
        pzLabel_2                     matlab.ui.control.Label
        pyIKDegEditField              matlab.ui.control.NumericEditField
        pyLabel_2                     matlab.ui.control.Label
        pxIKDegEditField              matlab.ui.control.NumericEditField
        pxLabel_2                     matlab.ui.control.Label
        CalcularIKButton              matlab.ui.control.Button
        IKfig                         matlab.ui.control.UIAxes
        EspaciodetrabajoTab           matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        CalcularWSButton              matlab.ui.control.Button
        WSfig                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularFKButton
        function CalcularFKButtonPushed(app, event)
            r = app.rEditField.Value;
            h = app.hEditField.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;

            phi1 = 0; phi2 = 2*pi/3; phi3 = -2*pi/3;
            
            q1Deg = app.q1FKDegEditField.Value;
            q2Deg = app.q2FKDegEditField.Value;
            q3Deg = app.q3FKDegEditField.Value;

            q1 = deg2rad(q1Deg); q2 = deg2rad(q2Deg); q3 = deg2rad(q3Deg);

            [px, py, pz, ~] = DeltaFK(r,h,a,b,phi1,phi2,phi3,q1,q2,q3);
            app.pxFKDegEditField.Value = double(px);
            app.pyFKDegEditField.Value = double(py);
            app.pzFKDegEditField.Value = double(pz);

            DeltaPlotUIAxes(app.FKfig,r,h,a,b,phi2,phi3,q1,q2,q3,px,py,pz)
        end

        % Button pushed function: CalcularIKButton
        function CalcularIKButtonPushed(app, event)
            r = app.rEditField.Value;
            h = app.hEditField.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;

            phi1 = 0; phi2 = 2*pi/3; phi3 = -2*pi/3;
            
            px = app.pxIKDegEditField.Value;
            py = app.pyIKDegEditField.Value;
            pz = app.pzIKDegEditField.Value;

            [q1, q2, q3, err] = DeltaIK(r,h,a,b,phi1,phi2,phi3,px,py,pz);
            if(err>0)
                fprintf('error');
            end
            q1Deg = rad2deg(q1); q2Deg = rad2deg(q2); q3Deg = rad2deg(q3);

            app.q1IKDegEditField.Value = double(q1Deg);
            app.q2IKDegEditField.Value = double(q2Deg);
            app.q3IKDegEditField.Value = double(q3Deg);

            DeltaPlotUIAxes(app.IKfig,r,h,a,b,phi2,phi3,q1,q2,q3,px,py,pz)
        end

        % Button pushed function: CalcularWSButton
        function CalcularWSButtonPushed(app, event)
            tic;
            r = app.rEditField.Value;
            h = app.hEditField.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;

            phi1 = 0; phi2 = 2*pi/3; phi3 = -2*pi/3;

            [Ps, Zmin, Zmax, ERR] = DeltaWorkSpace(r, h, a, b, phi1, phi2, phi3);

            if(ERR == 1)
                fprintf('Singularidad no contemplada\n');
            end

            cla(app.WSfig);
            
            [px, py, pz, ~] = DeltaFK(r,h,a,b,phi1,phi2,phi3,0,0,0);
            DeltaPlotUIAxes(app.WSfig,r,h,a,b,phi2,phi3,0,0,0,px,py,pz)

            grid(app.WSfig,'on')
            hold(app.WSfig,'on')
            plot3(app.WSfig, Ps(:,1), Ps(:,2), Ps(:,3), '.', 'color', [0.4510 0.1137 0.6588], 'MarkerSize',1)
            axis(app.WSfig,'equal')
            app.WSfig.ZLim = [Zmin-300 Zmax+100];

            [sum, middleZ, X, Y, Z, Q1, Q2, Q3] = Cuboid(r, h, a, b, phi1, phi2, phi3);
            plotcubeUIAxes(app.WSfig, [2*sum 2*sum 2*sum], [0  0  middleZ], .3, [0 1 0])
            hold(app.WSfig,'off')

            app.TextArea.Value = "Espacio de cuboide:";
            app.TextArea.Value = [app.TextArea.Value;"X = " + num2str(X(1)) + " a " + num2str(X(2))];
            app.TextArea.Value = [app.TextArea.Value;"Y = " + num2str(Y(1)) + " a " + num2str(Y(2))];
            app.TextArea.Value = [app.TextArea.Value;"Z = " + num2str(Z(1)) + " a " + num2str(Z(2))];
            app.TextArea.Value = [app.TextArea.Value;"L = " + num2str(2*sum)];
            app.TextArea.Value = [app.TextArea.Value;"Límites de ángulo de motor"];
            app.TextArea.Value = [app.TextArea.Value;"q1 = " + num2str(Q1(1)) + "° a " + num2str(Q1(2)) + "°"];
            app.TextArea.Value = [app.TextArea.Value;"q2 = " + num2str(Q2(1)) + "° a " + num2str(Q2(2)) + "°"];
            app.TextArea.Value = [app.TextArea.Value;"q3 = " + num2str(Q3(1)) + "° a " + num2str(Q3(2)) + "°"];
            toc;
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.UIFigure,[filepath filename]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CinemticaDirectaTab = uitab(app.TabGroup);
            app.CinemticaDirectaTab.Title = 'Cinemática Directa';
            app.FKfig = uiaxes(app.CinemticaDirectaTab);
            title(app.FKfig, 'Cinemática directa')
            xlabel(app.FKfig, 'X')
            ylabel(app.FKfig, 'Y')
            zlabel(app.FKfig, 'Z')
            app.FKfig.ZDir = 'reverse';
            app.CalcularFKButton = uibutton(app.CinemticaDirectaTab, 'push');
            app.CalcularFKButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularFKButtonPushed, true);
            app.CalcularFKButton.Text = 'Calcular FK';
            app.q1degEditFieldLabel = uilabel(app.CinemticaDirectaTab);
            app.q1degEditFieldLabel.Text = 'q1 (deg)';
            app.q1FKDegEditField = uieditfield(app.CinemticaDirectaTab, 'numeric');
            app.q1FKDegEditField.ValueDisplayFormat = '%.3f°';
            app.q2degLabel = uilabel(app.CinemticaDirectaTab);
            app.q2degLabel.Text = 'q2 (deg)';
            app.q2FKDegEditField = uieditfield(app.CinemticaDirectaTab, 'numeric');
            app.q2FKDegEditField.ValueDisplayFormat = '%.3f°';
            app.q3degLabel = uilabel(app.CinemticaDirectaTab);
            app.q3degLabel.Text = 'q3 (deg)';
            app.q3FKDegEditField = uieditfield(app.CinemticaDirectaTab, 'numeric');
            app.q3FKDegEditField.ValueDisplayFormat = '%.3f°';
            app.pxLabel = uilabel(app.CinemticaDirectaTab);
            app.pxLabel.Text = 'px';
            app.pxFKDegEditField = uieditfield(app.CinemticaDirectaTab, 'numeric');
            app.pxFKDegEditField.ValueDisplayFormat = '%.3f';
            app.pxFKDegEditField.Editable = 'off';
            app.pyLabel = uilabel(app.CinemticaDirectaTab);
            app.pyLabel.Text = 'py';
            app.pyFKDegEditField = uieditfield(app.CinemticaDirectaTab, 'numeric');
            app.pyFKDegEditField.ValueDisplayFormat = '%.3f';
            app.pyFKDegEditField.Editable = 'off';
            app.pzLabel = uilabel(app.CinemticaDirectaTab);
            app.pzLabel.Text = 'pz';
            app.pzFKDegEditField = uieditfield(app.CinemticaDirectaTab, 'numeric');
            app.pzFKDegEditField.ValueDisplayFormat = '%.3f';
            app.pzFKDegEditField.Editable = 'off';
            app.CinemticaInversaTab = uitab(app.TabGroup);
            app.CinemticaInversaTab.Title = 'Cinemática Inversa';
            app.IKfig = uiaxes(app.CinemticaInversaTab);
            title(app.IKfig, 'Cinemática inversa')
            xlabel(app.IKfig, 'X')
            ylabel(app.IKfig, 'Y')
            zlabel(app.IKfig, 'Z')
            app.IKfig.ZDir = 'reverse';
            app.CalcularIKButton = uibutton(app.CinemticaInversaTab, 'push');
            app.CalcularIKButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIKButtonPushed, true);
            app.CalcularIKButton.Text = 'Calcular IK';
            app.pxLabel_2 = uilabel(app.CinemticaInversaTab);
            app.pxLabel_2.Text = 'px';
            app.pxIKDegEditField = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.pxIKDegEditField.ValueDisplayFormat = '%.3f';
            app.pyLabel_2 = uilabel(app.CinemticaInversaTab);
            app.pyLabel_2.Text = 'py';
            app.pyIKDegEditField = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.pyIKDegEditField.ValueDisplayFormat = '%.3f';
            app.pzLabel_2 = uilabel(app.CinemticaInversaTab);
            app.pzLabel_2.Text = 'pz';
            app.pzIKDegEditField = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.pzIKDegEditField.ValueDisplayFormat = '%.3f';
            app.pzIKDegEditField.Value = 500;
            app.pxLabel_3 = uilabel(app.CinemticaInversaTab);
            app.pxLabel_3.Text = 'q1 (deg)';
            app.q1IKDegEditField = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.q1IKDegEditField.ValueDisplayFormat = '%.3f°';
            app.q1IKDegEditField.Editable = 'off';
            app.pxLabel_4 = uilabel(app.CinemticaInversaTab);
            app.pxLabel_4.Text = 'q2 (deg)';
            app.q2IKDegEditField = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.q2IKDegEditField.ValueDisplayFormat = '%.3f°';
            app.q2IKDegEditField.Editable = 'off';
            app.pxLabel_5 = uilabel(app.CinemticaInversaTab);
            app.pxLabel_5.Text = 'q3 (deg)';
            app.q3IKDegEditField = uieditfield(app.CinemticaInversaTab, 'numeric');
            app.q3IKDegEditField.ValueDisplayFormat = '%.3f°';
            app.q3IKDegEditField.Editable = 'off';
            app.EspaciodetrabajoTab = uitab(app.TabGroup);
            app.EspaciodetrabajoTab.Title = 'Espacio de trabajo';
            app.WSfig = uiaxes(app.EspaciodetrabajoTab);
            title(app.WSfig, 'Espacio de trabajo')
            xlabel(app.WSfig, 'X')
            ylabel(app.WSfig, 'Y')
            zlabel(app.WSfig, 'Z')
            app.WSfig.ZDir = 'reverse';
            app.CalcularWSButton = uibutton(app.EspaciodetrabajoTab, 'push');
            app.CalcularWSButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularWSButtonPushed, true);
            app.CalcularWSButton.Text = 'Calcular WS';
            app.TextArea = uitextarea(app.EspaciodetrabajoTab);
            app.Autores = uilabel(app.UIFigure);
            app.Autores.Text = {'Instituto Politécnico Nacional'; ''; 'Unidad Profesional Interdiciplinaria en Ingeniería y Tecnologías Avanzadas'; ''; 'Cuevas Morales Saúl Asís'; 'Morales Flores Luis Fernando'};
            app.Titulo = uilabel(app.UIFigure);
            app.Titulo.Text = 'Cinemática Robot Delta';
            app.RadiobasefijarEditFieldLabel = uilabel(app.UIFigure);
            app.RadiobasefijarEditFieldLabel.Text = 'Radio base fija (r)';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditField.ValueDisplayFormat = '%.3f';
            app.rEditField.Value = 100;
            app.RadiobasemvilhEditFieldLabel = uilabel(app.UIFigure);
            app.RadiobasemvilhEditFieldLabel.Text = 'Radio base móvil (h)';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditField.ValueDisplayFormat = '%.3f';
            app.hEditField.Value = 65;
            app.BrazorLabel = uilabel(app.UIFigure);
            app.BrazorLabel.Text = 'Bicep (a)';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueDisplayFormat = '%.3f';
            app.aEditField.Value = 280;
            app.BrazorLabel_2 = uilabel(app.UIFigure);
            app.BrazorLabel_2.Text = 'Antebrazo (b)';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.ValueDisplayFormat = '%.3f';
            app.bEditField.Value = 350;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_IPN.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'Logo_UPIITA.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaKinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end